classdef GUIDemo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        File                       matlab.ui.container.Menu
        tabGroupMain               matlab.ui.container.TabGroup
        tabRobot                   matlab.ui.container.Tab
        panelRobot                 matlab.ui.container.Panel
        treeRobot                  matlab.ui.container.Tree
        nodeSerialRobot            matlab.ui.container.TreeNode
        panelLink                  matlab.ui.container.Panel
        tabGroupLinkDef            matlab.ui.container.TabGroup
        tabLinkSummary             matlab.ui.container.Tab
        tabLinkKine                matlab.ui.container.Tab
        panelLinkDefOptions        matlab.ui.container.Panel
        GridLayout                 matlab.ui.container.GridLayout
        GeometryLabel              matlab.ui.control.Label
        dropDownGeometry           matlab.ui.control.DropDown
        JointTypeDropDownLabel     matlab.ui.control.Label
        dropDownJointType          matlab.ui.control.DropDown
        panelGeometry              matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        EditFieldLabel             matlab.ui.control.Label
        fieldKineDOrZDir           matlab.ui.control.NumericEditField
        EditField2Label            matlab.ui.control.Label
        fieldKineThetaOrLength     matlab.ui.control.NumericEditField
        EditField3Label            matlab.ui.control.Label
        fieldKineAOrVertOffset     matlab.ui.control.NumericEditField
        EditField4Label            matlab.ui.control.Label
        fieldKineAlphaOrAngOffset  matlab.ui.control.NumericEditField
        tabLinkDynamics            matlab.ui.container.Tab
        ConsoleTextAreaLabel       matlab.ui.control.Label
        ConsoleTextArea            matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        baseLink = Link % default base link
        robot = SerialLink % makes a null robot
        
        % vector to store nodes for the robot tree (treebot, if you will)
        treeRobotNodes = []
        % Note: Should mirror the robot.links property at all times
        
    end

    
    methods (Access = private)
        
        function populateTreeRobot(app)
        % populates the tree with the SerialLink object's links
            for i = 1:length(app.robot.links)
                linkName = "Link " + num2str(i);
                nodeStruct = struct("index", i, "name", linkName);
                app.treeRobotNodes = [app.treeRobotNodes uitreenode(app.nodeSerialRobot)];
                app.treeRobotNodes(i).Text = nodeStruct.name;
                app.treeRobotNodes(i).NodeData = nodeStruct;
            end
        end
        
        function populateTableGeometry(app)
            % placeholder method for populating the robot geometry table
            if app.dropDownGeometry.Value == "DH Parameters"
                app.EditFieldLabel.Text = "D";
                app.EditField2Label.Text = "Theta";
                app.EditField3Label.Text = "A";
                app.EditField4Label.Text = "Alpha";
                if app.dropDownJointType.Value == "Revolute"   
                    app.fieldKineThetaOrLength.Editable = 0;
                    app.fieldKineThetaOrLength.FontColor = '#555555'; % should be a greyish color
                else % other option is prismatic
                    app.fieldKineDOrZDir.Editable = 0;
                    app.fieldKineDOrZDir.FontColor = '#555555'; % should be a greyish color
                end
            else % general geometric description
                app.EditFieldLabel.Text = "Joint Direction";
                app.EditField2Label.Text = "Link Length";
                app.EditField3Label.Text = "Vertical Offset";
                app.EditField4Label.Text = "Angular Offset";
               if app.dropDownJointType.Value == "Revolute"
                   app.fieldKineThetaOrLength.Editable = 0;
                   app.fieldKineThetaOrLength.FontColor = '#555555'; % should be a greyish color
               else % other option is prismatic
                   app.fieldKineAOrVertOffset.Editable = 0;
                   app.fieldKineAOrVertOffset.FontColor = '#555555'; % should be a greyish color
               end
            end
        end
        
        function updateGeometryTable(app, index)
            % updates Geoemetry table based on which link was selected
            %app.print(index);
            display(index);
            app.print("Updating Geoemtry Table with Link " + num2str(index) + "'s information...");
        end
        
        function print(app, str)
            app.ConsoleTextArea.Value = string([app.ConsoleTextArea.Value newline str]);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupCallback(app)
            % Callback to be ran on app startup. Should initialize all
            % default values.
            
            %% Robot Tab
            
            % Initialize default link and default SerialLink robot
            app.baseLink = Link();
            app.robot = SerialLink([app.baseLink]);
            
            % give null robot the baselink
            app.robot.name = "Default Robot";
            
            % populate treeRobot with default robot
            app.populateTreeRobot();
            
            
        end

        % Selection changed function: treeRobot
        function treeRobotSelectionChanged(app, event)
            % callback for changing selected nodes on the treeRobot tree
            % Should update the link description area with information
            % about that node
            
            selectedNodes = app.treeRobot.SelectedNodes; % returns treeNode object
            
            % find out which treeNode is selected and update panels
            % accordingly
            app.updateGeometryTable(selectedNodes.NodeData.index);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.File = uimenu(app.UIFigure);
            app.File.Text = 'File';
            app.tabGroupMain = uitabgroup(app.UIFigure);
            app.tabRobot = uitab(app.tabGroupMain);
            app.tabRobot.Tooltip = {'Define your masterful robot here!'};
            app.tabRobot.Title = 'Robot';
            app.panelRobot = uipanel(app.tabRobot);
            app.panelRobot.Title = 'Robot Description';
            app.treeRobot = uitree(app.tabRobot);
            app.treeRobot.SelectionChangedFcn = createCallbackFcn(app, @treeRobotSelectionChanged, true);
            app.nodeSerialRobot = uitreenode(app.treeRobot);
            app.nodeSerialRobot.Text = 'Default Serial Robot';
            app.panelLink = uipanel(app.tabRobot);
            app.panelLink.Title = 'Link Descritpion';
            app.tabGroupLinkDef = uitabgroup(app.panelLink);
            app.tabLinkSummary = uitab(app.tabGroupLinkDef);
            app.tabLinkSummary.Title = 'Summary';
            app.tabLinkKine = uitab(app.tabGroupLinkDef);
            app.tabLinkKine.Title = 'Kinematics';
            app.panelLinkDefOptions = uipanel(app.tabLinkKine);
            app.panelLinkDefOptions.Title = 'Definition Options';
            app.GridLayout = uigridlayout(app.panelLinkDefOptions);
            app.GeometryLabel = uilabel(app.GridLayout);
            app.GeometryLabel.Text = 'Geometry:';
            app.dropDownGeometry = uidropdown(app.GridLayout);
            app.dropDownGeometry.Items = {'DH Parameters', 'Geometric Description', ''};
            app.dropDownGeometry.Value = 'DH Parameters';
            app.JointTypeDropDownLabel = uilabel(app.GridLayout);
            app.JointTypeDropDownLabel.Text = 'Joint Type:';
            app.dropDownJointType = uidropdown(app.GridLayout);
            app.dropDownJointType.Items = {'Revolute', 'Prismatic', ''};
            app.dropDownJointType.Value = 'Revolute';
            app.panelGeometry = uipanel(app.tabLinkKine);
            app.panelGeometry.Title = 'Link Geometry';
            app.GridLayout2 = uigridlayout(app.panelGeometry);
            app.EditFieldLabel = uilabel(app.GridLayout2);
            app.EditFieldLabel.Text = 'Edit Field';
            app.fieldKineDOrZDir = uieditfield(app.GridLayout2, 'numeric');
            app.EditField2Label = uilabel(app.GridLayout2);
            app.EditField2Label.Text = 'Edit Field2';
            app.fieldKineThetaOrLength = uieditfield(app.GridLayout2, 'numeric');
            app.EditField3Label = uilabel(app.GridLayout2);
            app.EditField3Label.Text = 'Edit Field3';
            app.fieldKineAOrVertOffset = uieditfield(app.GridLayout2, 'numeric');
            app.EditField4Label = uilabel(app.GridLayout2);
            app.EditField4Label.Text = 'Edit Field4';
            app.fieldKineAlphaOrAngOffset = uieditfield(app.GridLayout2, 'numeric');
            app.tabLinkDynamics = uitab(app.tabGroupLinkDef);
            app.tabLinkDynamics.Title = 'Dynamics';
            app.ConsoleTextAreaLabel = uilabel(app.UIFigure);
            app.ConsoleTextAreaLabel.Text = {'Console'; ''};
            app.ConsoleTextArea = uitextarea(app.UIFigure);
            app.ConsoleTextArea.Editable = 'off';
            app.ConsoleTextArea.Value = {'Hello and welcome!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUIDemo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupCallback)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end